classdef OperacionesBasicas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FactorDesphButton               matlab.ui.control.Button
        DesplazamientoButton_h          matlab.ui.control.Button
        DespFButton                     matlab.ui.control.Button
        FactorDespxButton               matlab.ui.control.Button
        DesplazamientoButton_x          matlab.ui.control.Button
        InstruccionesLabel_4            matlab.ui.control.Label
        InstruccionesLabel_3            matlab.ui.control.Label
        InstruccionesLabel_2            matlab.ui.control.Label
        InstruccionesLabel_1            matlab.ui.control.Label
        InstruccionesLabel              matlab.ui.control.Label
        RestaButton                     matlab.ui.control.Button
        SumaButton                      matlab.ui.control.Button
        AtnFButton                      matlab.ui.control.Button
        CancelarButton_h                matlab.ui.control.Button
        CancelarButton_x                matlab.ui.control.Button
        CancelarButton                  matlab.ui.control.Button
        AmpFButton                      matlab.ui.control.Button
        FactorkEditField                matlab.ui.control.NumericEditField
        FactorkLabel                    matlab.ui.control.Label
        FactorAtnhButton                matlab.ui.control.Button
        FactorAtnxButton                matlab.ui.control.Button
        FactorAmpxEditField             matlab.ui.control.NumericEditField
        FactordeamplificacinkEditFieldLabel_3  matlab.ui.control.Label
        FactorAmphEditField             matlab.ui.control.NumericEditField
        FactordeamplificacinkEditFieldLabel_2  matlab.ui.control.Label
        FactorAmphButton                matlab.ui.control.Button
        FactorAmpxButton                matlab.ui.control.Button
        SealresultadoLabel              matlab.ui.control.Label
        AtenuacinButton_h               matlab.ui.control.Button
        AmplificacinButton_h            matlab.ui.control.Button
        AtenuacinButton_x               matlab.ui.control.Button
        AmplificacinButton_x            matlab.ui.control.Button
        ConsejoLabel                    matlab.ui.control.Label
        inicialEditField_hn             matlab.ui.control.NumericEditField
        PosicininicialEditField_2Label  matlab.ui.control.Label
        inicialEditField_xn             matlab.ui.control.NumericEditField
        PosicininicialEditFieldLabel_2  matlab.ui.control.Label
        GraficarhnButton                matlab.ui.control.Button
        GraficarxnButton                matlab.ui.control.Button
        Practica1OperacionesbasicasLabel  matlab.ui.control.Label
        DesplazamientoButton_F          matlab.ui.control.Button
        ReflejoButton                   matlab.ui.control.Button
        AtenuacinButton_F               matlab.ui.control.Button
        AmplificacinButton_F            matlab.ui.control.Button
        DiezmacinButton                 matlab.ui.control.Button
        InterpolacinButton              matlab.ui.control.Button
        ConvolucinButton                matlab.ui.control.Button
        SumaRestaButton                 matlab.ui.control.Button
        IntroduzcasussealesLabel        matlab.ui.control.Label
        xnEditField                     matlab.ui.control.EditField
        xnEditFieldLabel                matlab.ui.control.Label
        hnEditField                     matlab.ui.control.EditField
        hnEditFieldLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_h                        matlab.ui.control.UIAxes
        UIAxes_x                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xn % Señal x(n)
        inicio_x % Desplazamioento para el punto inicial de x(n)
        
        hn % Señal h(n)
        inicio_h % Desplazamioento para el punto inicial de h(n)
        
        Fn % Señal F(n)
        inicio_F % Desplazamioento para el punto inicial de F(n)
    end
    
    methods (Access = private)
        
        function visualizarOpc(app)
            app.DiezmacinButton.Visible = 'on';
            app.InterpolacinButton.Visible = 'on';
            app.AmplificacinButton_F.Visible = 'on';
            app.AtenuacinButton_F.Visible = 'on';
            app.ReflejoButton.Visible = 'on';
            app.DesplazamientoButton_F.Visible = 'on';
        end
        
        function plotXn(app)
            cla(app.UIAxes_x, 'reset');
            stem(app.UIAxes_x, app.inicio_x, app.xn,'filled','markersize', 4, "Color", [0.6350, 0.0780, 0.1840]); 
            grid(app.UIAxes_x, 'on');
            xline(app.UIAxes_x, 0);
            title(app.UIAxes_x,'Señal x(n)');
            xlabel(app.UIAxes_x,'n'); 
            ylabel(app.UIAxes_x,'x(n)'); 
        end
        
        function plotHn(app)
            cla(app.UIAxes_h, 'reset');
            stem(app.UIAxes_h, app.inicio_h, app.hn, 'filled','markersize', 4, "Color", [0, 0.4470, 0.7410]); 
            grid(app.UIAxes_h, 'on');
            xline(app.UIAxes_h, 0);
            title(app.UIAxes_h,'Señal h(n)');
            xlabel(app.UIAxes_h,'n'); 
            ylabel(app.UIAxes_h,'h(n)'); 
        end
        
        function plotFn(app)
            cla(app.UIAxes, 'reset');
            stem(app.UIAxes, app.inicio_F, app.Fn, 'filled','markersize', 4, "Color",[0.4660, 0.6740, 0.1880]); 
            grid(app.UIAxes, 'on');
            xline(app.UIAxes, 0);
            title(app.UIAxes,'Señal F(n)');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
            
        end
        
        function valoresDefinidos(app)
            if length(app.xn) > 0 && length(app.hn) > 0
                app.InstruccionesLabel.Visible = 'off';
                app.InstruccionesLabel_1.Visible = 'off';
                app.InstruccionesLabel_2.Visible = 'off';
                app.InstruccionesLabel_3.Visible = 'off';
                app.InstruccionesLabel_4.Visible = 'off';
                app.UIAxes.Visible = 'on';
                app.SealresultadoLabel.Visible = 'on';
                app.ConvolucinButton.Visible = 'on';
                app.SumaRestaButton.Visible = 'on';
            end
        end
        
        function [resultadoS,niS] = Convolucion(app, x, y, nix, niy)
            
            if length(x) > length(y)
                y(numel(x)) = 0;
            elseif length(y) > length(x)
                x(numel(y)) = 0;
            end
            
            if length(nix) > length(niy)
                niy(numel(nix)) = 0;
            elseif length(niy) > length(nix)
                nix(numel(niy)) = 0;
            end
            
            % Calculamos el tamaño de la matriz
            N = length(x) + length(y) - 1;
            diferencia = N - length(x);
        
            xVolteado = fliplr(x);
        
            % Llenamos la primer fila de la matriz
            fila = [];
            Cx = [];
        
            % Colocamos el primer valor
            fila = [x(1)];
            % Colocamos los 0's correspondientes para el llenado de la matriz
            for i=1:diferencia
                fila = [fila, 0]; 
            end
            % Colocamos los valores restantes de nuestra primera función
            for i=1:(length(x)-1)
                fila = [fila, xVolteado(i)];
            end
            Cx = [fila];
            columnaCopia = fila;
            fila = [];
        
            % Llenamos el resto de filas de la matriz con base a la primer fila
            for i=1:(N-1)
                fila = [columnaCopia(N)];
                for j=1:(N-1)
                    fila = [fila, columnaCopia(j)];
                end
                Cx = [Cx; fila];
                columnaCopia = fila;
                fila = [];
            end
        
            % Rellenamos de 0's nuestra segunda función para que tenga el tamaño de
            % la matriz
            yc = y;
            for i=1:(N-length(yc))
                yc = [yc, 0];
            end
        
            % Realizamos la multiplicación de matrices
            suma = [];
            valor = 0;
            resultado = [];
            for i=1:N
                fila = Cx(i,:);
                for j=1:N
                    valor = valor + (fila(j)*yc(j));
                end
                resultado = [resultado, valor];
                valor = 0;
            end
        
            ni = -nix-niy;
            ni = -ni;
            
            ni = ni:length(resultado)-(-ni + 1);
            
            
            resultadoS = resultado;
            niS = ni;
        end
        
        
        function [resultadoS, niS] = Suma(app, x,y,nix, niy)
             %tamaño del vector X y del vector Y
            tam_x = length(x);
            tam_y = length(y);
            vec = [];
            
            if(nix == niy) %cuando ambas posiciones iniciales son iguales
                diferencia = abs(tam_x-tam_y);
                if(tam_x > tam_y)
                    for i=1:diferencia
                        y = [y 0];
                    end
                elseif(tam_y > tam_x) 
                    for i=1:diferencia
                        x = [x 0];
                    end
                end
                vec = x + y;
                ni_vec = nix;
            else    %cuando las posiciones iniciales de los vectores son diferetentes
                dif_ladox = tam_x - nix + 1;
                dif_ladoy = tam_y - niy + 1;
                ceros_derecha = abs(dif_ladox - dif_ladoy);
                ceros_izquierda = abs(nix - niy);
                if(nix < niy)   %si la posición inicial del vector x es menor que la de y
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        x = [0 x];  %agrega los ceros correspondientes a la izquierda de x
                    end
                    vec = x + y;     %ahora que los vectores son iguales, se suman
                    ni_vec = niy;
                elseif(niy < nix)
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        y = [0 y];  %agrega los ceros correspondientes a la izquierda de y
                    end
                    vec = x + y;     %ahora que los vectores son iguales, se suman
                    ni_vec = nix;
                end
            end
                
            
            ni = ni_vec;
            ni = -ni;
            ni = ni:length(vec)-ni_vec-1;
            
            resultadoS = vec;
            niS = ni;
            x
            y
            vec
            ni
        end
        
        function [resultadoS, niS] = Resta(app,x,y,nix, niy)
            %tamaño del vector X y del vector Y
            tam_x = length(x);
            tam_y = length(y);
            vec = [];
            
            if(nix == niy) %cuando ambas posiciones iniciales son iguales
                diferencia = abs(tam_x-tam_y);
                if(tam_x > tam_y)
                    for i=1:diferencia
                        y = [y 0];
                    end
                elseif(tam_y > tam_x) 
                    for i=1:diferencia
                        x = [x 0];
                    end
                end
                vec = x - y;
                ni_vec = nix;
            else    %cuando las posiciones iniciales de los vectores son diferetentes
                dif_ladox = tam_x - nix + 1;
                dif_ladoy = tam_y - niy + 1;
                ceros_derecha = abs(dif_ladox - dif_ladoy);
                ceros_izquierda = abs(nix - niy);
                if(nix < niy)   %si la posición inicial del vector x es menor que la de y
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        x = [0 x];  %agrega los ceros correspondientes a la izquierda de x
                    end
                    vec = x - y;     %ahora que los vectores son iguales, se suman
                    ni_vec = niy;
                elseif(niy < nix)
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        y = [0 y];  %agrega los ceros correspondientes a la izquierda de y
                    end
                    vec = x - y;     %ahora que los vectores son iguales, se restan los vectores
                    ni_vec = nix;
                end
            end
                
            
            ni = ni_vec;
            ni = -ni;
            ni = ni:length(vec)-ni_vec-1;
            
            resultadoS = vec;
            niS = ni;
            x
            y
            vec
            ni
        end
        
        function [resultadoS,niS] = Reflejo(app, x, nix)
            x_aux = [];
            for i=1:length(x)
                x_aux = [x(i) x_aux];
            end
            inicio = length(nix);
            ni = length(x)-inicio-1;
            ni = -ni;
            ni = ni:inicio;
            resultadoS = x_aux;
            niS = ni;
            nix
            ni
            x_aux
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarxnButton
        function GraficarxnButtonPushed(app, event)
             cla(app.UIAxes_x, 'reset');
             vec = str2double(strsplit(app.xnEditField.Value)); 
             inicial = app.inicialEditField_xn.Value;
             if (inicial < length(vec) && inicial >= 0)
                xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             app.xn = vec;
             app.inicio_x = xi;
             plotXn(app);
             
             app.AmplificacinButton_x.Visible = 'on';
             app.AtenuacinButton_x.Visible = 'on';
             app.DesplazamientoButton_x.Visible = 'on';
             valoresDefinidos(app);
             
        end

        % Button pushed function: GraficarhnButton
        function GraficarhnButtonPushed(app, event)
             cla(app.UIAxes_h, 'reset');
             vec = str2double(strsplit(app.hnEditField.Value)); 
             inicial = app.inicialEditField_hn.Value;
             if (inicial < length(vec) && inicial >= 0)
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             app.hn = vec;
             app.inicio_h = xi;
             plotHn(app);
             
             app.AmplificacinButton_h.Visible = 'on';
             app.AtenuacinButton_h.Visible = 'on';
             app.DesplazamientoButton_h.Visible = 'on';
             
             valoresDefinidos(app);
             
        end

        % Button pushed function: AmplificacinButton_x
        function AmplificacinButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor de amplificación (k) =';
            app.FactorAmpxButton.Visible = 'on';
            app.FactorAtnxButton.Visible = 'off';
            app.CancelarButton_x.Visible = 'on';
            app.FactorDespxButton.Visible = 'off';
        end

        % Button pushed function: FactorAmpxButton
        function FactorAmpxButtonPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            app.xn = app.xn * k;
            plotXn(app);
        end

        % Button pushed function: FactorAtnxButton
        function FactorAtnxButtonPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            app.xn = app.xn / k;
            plotXn(app);
            
        end

        % Button pushed function: AtenuacinButton_x
        function AtenuacinButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor de atenuación (k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'on';
            app.CancelarButton_x.Visible = 'on';
            app.FactorDespxButton.Visible = 'off';
        end

        % Button pushed function: AmplificacinButton_h
        function AmplificacinButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor de amplificación (k) =';
            app.FactorAmphButton.Visible = 'on';
            app.FactorAtnhButton.Visible = 'off';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
        end

        % Button pushed function: AtenuacinButton_h
        function AtenuacinButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor de atenuación (k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'on';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
        end

        % Button pushed function: FactorAmphButton
        function FactorAmphButtonPushed(app, event)
            k = app.FactorAmphEditField.Value;
            app.hn = app.hn * k;
            plotHn(app);
        end

        % Button pushed function: FactorAtnhButton
        function FactorAtnhButtonPushed(app, event)
            k = app.FactorAmphEditField.Value;
            app.hn = app.hn / k;
            plotHn(app);
        end

        % Button pushed function: CancelarButton_x
        function CancelarButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'off';
            app.FactorDespxButton.Visible = 'off';
            app.CancelarButton_x.Visible = 'off';
        end

        % Button pushed function: CancelarButton_h
        function CancelarButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'off';
        end

        % Button pushed function: AmplificacinButton_F
        function AmplificacinButton_FPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'on';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'off';
            app.FactorkLabel.Visible = 'off';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.CancelarButton.Visible = 'off';
        end

        % Button pushed function: AtenuacinButton_F
        function AtenuacinButton_FPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'on';
            app.DespFButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
           
        end

        % Button pushed function: ConvolucinButton
        function ConvolucinButtonPushed(app, event)
            visualizarOpc(app);
            [app.Fn, app.inicio_F] = Convolucion(app,app.xn, app.hn, app.inicio_x, app.inicio_h);
            disp(app.Fn);
            disp(app.inicio_F);
            plotFn(app);
        end

        % Button pushed function: AtnFButton
        function AtnFButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            app.Fn = app.Fn / k;
            plotFn(app);
        end

        % Button pushed function: AmpFButton
        function AmpFButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            app.Fn = app.Fn * k;
            plotFn(app); 
        end

        % Button pushed function: SumaRestaButton
        function SumaRestaButtonPushed(app, event)
            app.RestaButton.Visible = 'on';
            app.SumaButton.Visible = 'on';
            app.FactorkEditField.Visible = 'off';
            app.FactorkLabel.Visible = 'off';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
        end

        % Button pushed function: DesplazamientoButton_x
        function DesplazamientoButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor desplazamiento(k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'off';
            app.FactorDespxButton.Visible = 'on';
            app.CancelarButton_x.Visible = 'on';
        end

        % Button pushed function: FactorDespxButton
        function FactorDespxButtonPushed(app, event)
             k = app.FactorAmpxEditField.Value;
             %Desplazar hacia arriba o abajo
%            if k>0
%                 app.xn = app.xn + abs(k);
%                 plotXn(app); 
%            else     
%                 app.xn = app.xn - abs(k);
%                 plotXn(app); 
%            end

            %%DESPLAZAR HACIA LA DERECHA O IZQUIERDA%%
            if k>0
                app.inicio_x = app.inicio_x - abs(k);
                plotXn(app); 
             else     
                app.inicio_x = app.inicio_x + abs(k);
                plotXn(app); 
             end


        end

        % Button pushed function: DesplazamientoButton_F
        function DesplazamientoButton_FPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'on';
            app.CancelarButton.Visible = 'on';
        end

        % Button pushed function: DespFButton
        function DespFButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            %%DESPLAZAR HACIA LA ARRIBA O ABAJO%%
%             if k>0
%                 app.Fn = app.Fn + abs(k);
%                 plotFn(app);
%             else
%                 app.Fn = app.Fn - abs(k);
%                 plotFn(app);
%             end

            %%DESPLAZAR HACIA LA DERECHA O IZQUIERDA%%
            if k>0
                app.inicio_F = app.inicio_F - abs(k);
                plotFn(app); 
             else     
                app.inicio_F = app.inicio_F + abs(k);
                plotFn(app); 
             end
        end

        % Button pushed function: SumaButton
        function SumaButtonPushed(app, event)
            visualizarOpc(app);
            [app.Fn, app.inicio_F] = Suma(app,app.xn, app.hn, app.inicialEditField_xn.Value, app.inicialEditField_hn.Value);
            disp(app.Fn);
            disp(app.inicio_F);
            plotFn(app);
        end

        % Button pushed function: ReflejoButton
        function ReflejoButtonPushed(app, event)
            [app.Fn, app.inicio_F] = Reflejo(app, app.Fn, app.inicio_F);
            disp(app.Fn);
            disp(app.inicio_F);
            plotFn(app);
        end

        % Button pushed function: RestaButton
        function RestaButtonPushed(app, event)
            visualizarOpc(app);
            [app.Fn, app.inicio_F] = Resta(app,app.xn, app.hn, app.inicialEditField_xn.Value, app.inicialEditField_hn.Value);
            disp(app.Fn);
            disp(app.inicio_F);
            plotFn(app);
        end

        % Button pushed function: DesplazamientoButton_h
        function DesplazamientoButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor desplazamiento(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.FactorDesphButton.Visible = 'on';
            app.CancelarButton_h.Visible = 'on';
        end

        % Button pushed function: FactorDesphButton
        function FactorDesphButtonPushed(app, event)
             k = app.FactorAmphEditField.Value;
             %Desplazar hacia arriba o abajo
%            if k>0
%                 app.xn = app.xn + abs(k);
%                 plotXn(app); 
%            else     
%                 app.xn = app.xn - abs(k);
%                 plotXn(app); 
%            end

            %%DESPLAZAR HACIA LA DERECHA O IZQUIERDA%%
            if k>0
                app.inicio_h = app.inicio_h - abs(k);
                plotHn(app); 
             else     
                app.inicio_h = app.inicio_h + abs(k);
                plotHn(app); 
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'Señal x(n)')
            xlabel(app.UIAxes_x, 'n')
            ylabel(app.UIAxes_x, 'x(n)')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_h = uiaxes(app.UIFigure);
            title(app.UIAxes_h, 'Señal h(n)')
            xlabel(app.UIAxes_h, 'n')
            ylabel(app.UIAxes_h, 'h(n)')
            zlabel(app.UIAxes_h, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal F(n)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.hnEditFieldLabel = uilabel(app.UIFigure);
            app.hnEditFieldLabel.Text = 'h(n) = ';
            app.hnEditField = uieditfield(app.UIFigure, 'text');
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x(n) = ';
            app.xnEditField = uieditfield(app.UIFigure, 'text');
            app.IntroduzcasussealesLabel = uilabel(app.UIFigure);
            app.IntroduzcasussealesLabel.Text = 'Introduzca sus señales';
            app.SumaRestaButton = uibutton(app.UIFigure, 'push');
            app.SumaRestaButton.ButtonPushedFcn = createCallbackFcn(app, @SumaRestaButtonPushed, true);
            app.SumaRestaButton.Visible = 'off';
            app.SumaRestaButton.Text = 'Suma / Resta';
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolucinButtonPushed, true);
            app.ConvolucinButton.Visible = 'off';
            app.ConvolucinButton.Text = 'Convolución';
            app.InterpolacinButton = uibutton(app.UIFigure, 'push');
            app.InterpolacinButton.Visible = 'off';
            app.InterpolacinButton.Text = 'Interpolación';
            app.DiezmacinButton = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton.Visible = 'off';
            app.DiezmacinButton.Text = 'Diezmación ';
            app.AmplificacinButton_F = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_F.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_FPushed, true);
            app.AmplificacinButton_F.Visible = 'off';
            app.AmplificacinButton_F.Text = 'Amplificación';
            app.AtenuacinButton_F = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_F.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_FPushed, true);
            app.AtenuacinButton_F.Visible = 'off';
            app.AtenuacinButton_F.Text = 'Atenuación';
            app.ReflejoButton = uibutton(app.UIFigure, 'push');
            app.ReflejoButton.ButtonPushedFcn = createCallbackFcn(app, @ReflejoButtonPushed, true);
            app.ReflejoButton.Visible = 'off';
            app.ReflejoButton.Text = 'Reflejo';
            app.DesplazamientoButton_F = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_F.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButton_FPushed, true);
            app.DesplazamientoButton_F.Visible = 'off';
            app.DesplazamientoButton_F.Text = 'Desplazamiento';
            app.Practica1OperacionesbasicasLabel = uilabel(app.UIFigure);
            app.Practica1OperacionesbasicasLabel.Text = 'Practica 1: Operaciones basicas';
            app.GraficarxnButton = uibutton(app.UIFigure, 'push');
            app.GraficarxnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarxnButtonPushed, true);
            app.GraficarxnButton.Text = 'Graficar x(n)';
            app.GraficarhnButton = uibutton(app.UIFigure, 'push');
            app.GraficarhnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarhnButtonPushed, true);
            app.GraficarhnButton.Text = 'Graficar h(n)';
            app.PosicininicialEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PosicininicialEditFieldLabel_2.Text = 'Posición inicial =';
            app.inicialEditField_xn = uieditfield(app.UIFigure, 'numeric');
            app.PosicininicialEditField_2Label = uilabel(app.UIFigure);
            app.PosicininicialEditField_2Label.Text = 'Posición inicial =';
            app.inicialEditField_hn = uieditfield(app.UIFigure, 'numeric');
            app.ConsejoLabel = uilabel(app.UIFigure);
            app.ConsejoLabel.Text = '*Las posiciones iniciales empiezan contando al primer elemento como 0';
            app.AmplificacinButton_x = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_xPushed, true);
            app.AmplificacinButton_x.Visible = 'off';
            app.AmplificacinButton_x.Text = 'Amplificación';
            app.AtenuacinButton_x = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_xPushed, true);
            app.AtenuacinButton_x.Visible = 'off';
            app.AtenuacinButton_x.Text = 'Atenuación';
            app.AmplificacinButton_h = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_hPushed, true);
            app.AmplificacinButton_h.Visible = 'off';
            app.AmplificacinButton_h.Text = 'Amplificación';
            app.AtenuacinButton_h = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_hPushed, true);
            app.AtenuacinButton_h.Visible = 'off';
            app.AtenuacinButton_h.Text = 'Atenuación';
            app.SealresultadoLabel = uilabel(app.UIFigure);
            app.SealresultadoLabel.Visible = 'off';
            app.SealresultadoLabel.Text = 'Señal resultado';
            app.FactorAmpxButton = uibutton(app.UIFigure, 'push');
            app.FactorAmpxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAmpxButtonPushed, true);
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAmpxButton.Text = 'Amplificar x(n)';
            app.FactorAmphButton = uibutton(app.UIFigure, 'push');
            app.FactorAmphButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAmphButtonPushed, true);
            app.FactorAmphButton.Visible = 'off';
            app.FactorAmphButton.Text = 'Amplificar h(n)';
            app.FactordeamplificacinkEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Text = 'Factor de amplificación (k) =';
            app.FactorAmphEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3 = uilabel(app.UIFigure);
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Text = 'Factor de amplificación (k) =';
            app.FactorAmpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorAmpxEditField.Visible = 'off';
            app.FactorAtnxButton = uibutton(app.UIFigure, 'push');
            app.FactorAtnxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAtnxButtonPushed, true);
            app.FactorAtnxButton.Visible = 'off';
            app.FactorAtnxButton.Text = 'Atenuar x(n)';
            app.FactorAtnhButton = uibutton(app.UIFigure, 'push');
            app.FactorAtnhButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAtnhButtonPushed, true);
            app.FactorAtnhButton.Visible = 'off';
            app.FactorAtnhButton.Text = 'Atenuar x(n)';
            app.FactorkLabel = uilabel(app.UIFigure);
            app.FactorkLabel.Visible = 'off';
            app.FactorkLabel.Text = 'Factor (k) = ';
            app.FactorkEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorkEditField.Visible = 'off';
            app.AmpFButton = uibutton(app.UIFigure, 'push');
            app.AmpFButton.ButtonPushedFcn = createCallbackFcn(app, @AmpFButtonPushed, true);
            app.AmpFButton.Visible = 'off';
            app.AmpFButton.Text = 'Amplificar F(n)';
            app.CancelarButton = uibutton(app.UIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Visible = 'off';
            app.CancelarButton.Text = 'Cancelar';
            app.CancelarButton_x = uibutton(app.UIFigure, 'push');
            app.CancelarButton_x.ButtonPushedFcn = createCallbackFcn(app, @CancelarButton_xPushed, true);
            app.CancelarButton_x.Visible = 'off';
            app.CancelarButton_x.Text = 'Cancelar';
            app.CancelarButton_h = uibutton(app.UIFigure, 'push');
            app.CancelarButton_h.ButtonPushedFcn = createCallbackFcn(app, @CancelarButton_hPushed, true);
            app.CancelarButton_h.Visible = 'off';
            app.CancelarButton_h.Text = 'Cancelar';
            app.AtnFButton = uibutton(app.UIFigure, 'push');
            app.AtnFButton.ButtonPushedFcn = createCallbackFcn(app, @AtnFButtonPushed, true);
            app.AtnFButton.Visible = 'off';
            app.AtnFButton.Text = 'Atenuar F(n)';
            app.SumaButton = uibutton(app.UIFigure, 'push');
            app.SumaButton.ButtonPushedFcn = createCallbackFcn(app, @SumaButtonPushed, true);
            app.SumaButton.Visible = 'off';
            app.SumaButton.Text = 'Suma';
            app.RestaButton = uibutton(app.UIFigure, 'push');
            app.RestaButton.ButtonPushedFcn = createCallbackFcn(app, @RestaButtonPushed, true);
            app.RestaButton.Visible = 'off';
            app.RestaButton.Text = 'Resta';
            app.InstruccionesLabel = uilabel(app.UIFigure);
            app.InstruccionesLabel.Text = 'Instrucciones';
            app.InstruccionesLabel_1 = uilabel(app.UIFigure);
            app.InstruccionesLabel_1.Text = '1) Escriba las muestras de su señal x(n), escriba la posición de la muestra que sera n = 0, grafiquela y aplique una amplificación o atenuación si lo desea.';
            app.InstruccionesLabel_2 = uilabel(app.UIFigure);
            app.InstruccionesLabel_2.Text = '2) Escriba las muestras de su señal h(n), escriba la posición de la muestra que sera n = 0, grafiquela y aplique una amplificación o atenuación si lo desea.';
            app.InstruccionesLabel_3 = uilabel(app.UIFigure);
            app.InstruccionesLabel_3.Text = '3) A partir de eso aparecera una tercera grafica, donde podra hacer las operaciones de suma, resta y convolución.';
            app.InstruccionesLabel_4 = uilabel(app.UIFigure);
            app.InstruccionesLabel_4.Text = '4) A esa tercera señal resultante de dichas operaciones anteriores, llamada F(n), se le podran hacer las operaciones de reflejo, desplazamiento, amplificación, etc.';
            app.DesplazamientoButton_x = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_x.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButton_xPushed, true);
            app.DesplazamientoButton_x.Visible = 'off';
            app.DesplazamientoButton_x.Text = 'Desplazamiento';
            app.FactorDespxButton = uibutton(app.UIFigure, 'push');
            app.FactorDespxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorDespxButtonPushed, true);
            app.FactorDespxButton.Visible = 'off';
            app.FactorDespxButton.Text = 'Desplazar x(n)';
            app.DespFButton = uibutton(app.UIFigure, 'push');
            app.DespFButton.ButtonPushedFcn = createCallbackFcn(app, @DespFButtonPushed, true);
            app.DespFButton.Visible = 'off';
            app.DespFButton.Text = 'Desplazar F(n)';
            app.DesplazamientoButton_h = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_h.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButton_hPushed, true);
            app.DesplazamientoButton_h.Visible = 'off';
            app.DesplazamientoButton_h.Text = 'Desplazamiento';
            app.FactorDesphButton = uibutton(app.UIFigure, 'push');
            app.FactorDesphButton.ButtonPushedFcn = createCallbackFcn(app, @FactorDesphButtonPushed, true);
            app.FactorDesphButton.Visible = 'off';
            app.FactorDesphButton.Text = 'Desplazar x(n)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperacionesBasicas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end